classdef WPB_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        OutputParameterPanel            matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ThisGUIisdevelopedbyDrRabeeShamassLabel  matlab.ui.control.Label
        SolveButton                     matlab.ui.control.StateButton
        WebPostbucklingResistantVkNEditField  matlab.ui.control.NumericEditField
        WebPostbucklingResistantVkNLabel  matlab.ui.control.Label
        InputParametersPanel            matlab.ui.container.Panel
        WebThicknesstwmmEditField       matlab.ui.control.NumericEditField
        WebThicknesstwmmEditFieldLabel  matlab.ui.control.Label
        FilletRadiusRmmEditField        matlab.ui.control.NumericEditField
        FilletRadiusRmmEditFieldLabel   matlab.ui.control.Label
        HoleWidthwmmEditField           matlab.ui.control.NumericEditField
        HoleWidthwmmEditFieldLabel      matlab.ui.control.Label
        OpeningHeightdommEditField      matlab.ui.control.NumericEditField
        OpeningHeightdommEditFieldLabel  matlab.ui.control.Label
        BeamHeightHmmEditField          matlab.ui.control.NumericEditField
        BeamHeightHmmEditFieldLabel     matlab.ui.control.Label
        LateralTorsionalBucklingResistanceofCellularBeamsLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SolveButton
        function SolveButtonValueChanged(app, event)
            value = app.SolveButton.Value;
            %Input parameters
h=app.BeamHeightHmmEditField.Value;
tw=app.WebThicknesstwmmEditField.Value;
do=app.OpeningHeightdommEditField.Value;
w=app.HoleWidthwmmEditField.Value;
R=app.FilletRadiusRmmEditField.Value;

w1=[3.83480242100000, 1.47939990600000, -3.48514722500000, -5.08500783200000, -2.42118457900000; 0.701281267000000, 0.343512449000000, -0.818285603000000, 1.08049274600000, -0.266021151000000; -0.432900923000000, -0.803603354000000, 0.502374335000000, 0.277830598000000, 0.0260740080000000; 0.103933747000000, -0.832704455000000, -0.0777399630000000, 0.900431184000000, 0.00561332600000000; -0.618667354000000, -0.656822477000000, 0.740513024000000, -0.811986064000000, 0.277154492000000];

w2=[0.335391972;-0.884948485;-2.562427349;1.983348679;-0.874157927];

b1=[-7.286010129;0.706355285;0.984210139;1.194325878;-0.661423531];

hn=(2*(h-213.36)/(1658.08-213.36))-1;
twn=(2*(tw-4.8)/(30-4.8))-1;
don=(2*(do-138.68)/(1492.27-138.68))-1;
wn=(2*(w-34.67)/(916.09-34.67))-1;
Rn=(2*(R-13.87)/(422.81-13.87))-1;


%%%%%%%%%%%%%%%%%
H1=b1(1) +hn*w1(1,1)+twn*w1(1,2)+don*w1(1,3)+wn*w1(1,4)+Rn*w1(1,5);
H2=b1(2) +hn*w1(2,1)+twn*w1(2,2)+don*w1(2,3)+wn*w1(2,4)+Rn*w1(2,5);
H3=b1(3) +hn*w1(3,1)+twn*w1(3,2)+don*w1(3,3)+wn*w1(3,4)+Rn*w1(3,5);
H4=b1(4) +hn*w1(4,1)+twn*w1(4,2)+don*w1(4,3)+wn*w1(4,4)+Rn*w1(4,5);
H5=b1(5) +hn*w1(5,1)+twn*w1(5,2)+don*w1(5,3)+wn*w1(5,4)+Rn*w1(5,5);
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

H1_A=(2/(1+exp(-2*H1))-1)*w2(1);
H2_A=(2/(1+exp(-2*H2))-1)*w2(2);
H3_A=(2/(1+exp(-2*H3))-1)*w2(3);
H4_A=(2/(1+exp(-2*H4))-1)*w2(4);
H5_A=(2/(1+exp(-2*H5))-1)*w2(5);


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

Vn=0.077617362+H1_A+H2_A+H3_A+H4_A+H5_A;

V=(Vn+1)*(4301.401-39.44328516)/(1+1)+39.44328516;
app.WebPostbucklingResistantVkNEditField.Value=V;

        end

        % Value changed function: BeamHeightHmmEditField
        function BeamHeightHmmEditFieldValueChanged(app, event)
            value = app.BeamHeightHmmEditField.Value;
            
        end

        % Value changed function: OpeningHeightdommEditField
        function OpeningHeightdommEditFieldValueChanged(app, event)
            value = app.OpeningHeightdommEditField.Value;
            
        end

        % Value changed function: HoleWidthwmmEditField
        function HoleWidthwmmEditFieldValueChanged(app, event)
            value = app.HoleWidthwmmEditField.Value;
            
        end

        % Value changed function: FilletRadiusRmmEditField
        function FilletRadiusRmmEditFieldValueChanged(app, event)
            value = app.FilletRadiusRmmEditField.Value;
            
        end

        % Value changed function: WebThicknesstwmmEditField
        function WebThicknesstwmmEditFieldValueChanged(app, event)
            value = app.WebThicknesstwmmEditField.Value;
            
        end

        % Value changed function: 
        % WebPostbucklingResistantVkNEditField
        function WebPostbucklingResistantVkNEditFieldValueChanged(app, event)
            value = app.WebPostbucklingResistantVkNEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LateralTorsionalBucklingResistanceofCellularBeamsLabel = uilabel(app.UIFigure);
            app.LateralTorsionalBucklingResistanceofCellularBeamsLabel.Text = 'Web-post buckling for perforated steel beams with elliptically-based web openings';
            app.InputParametersPanel = uipanel(app.UIFigure);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.BeamHeightHmmEditFieldLabel = uilabel(app.InputParametersPanel);
            app.BeamHeightHmmEditFieldLabel.Text = 'Beam Height, H (mm)';
            app.BeamHeightHmmEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.BeamHeightHmmEditField.ValueChangedFcn = createCallbackFcn(app, @BeamHeightHmmEditFieldValueChanged, true);
            app.OpeningHeightdommEditFieldLabel = uilabel(app.InputParametersPanel);
            app.OpeningHeightdommEditFieldLabel.Text = 'Opening Height, do (mm)';
            app.OpeningHeightdommEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.OpeningHeightdommEditField.ValueChangedFcn = createCallbackFcn(app, @OpeningHeightdommEditFieldValueChanged, true);
            app.HoleWidthwmmEditFieldLabel = uilabel(app.InputParametersPanel);
            app.HoleWidthwmmEditFieldLabel.Text = 'Hole Width, w (mm)';
            app.HoleWidthwmmEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.HoleWidthwmmEditField.ValueChangedFcn = createCallbackFcn(app, @HoleWidthwmmEditFieldValueChanged, true);
            app.FilletRadiusRmmEditFieldLabel = uilabel(app.InputParametersPanel);
            app.FilletRadiusRmmEditFieldLabel.Text = 'Fillet Radius , R (mm)';
            app.FilletRadiusRmmEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.FilletRadiusRmmEditField.ValueChangedFcn = createCallbackFcn(app, @FilletRadiusRmmEditFieldValueChanged, true);
            app.WebThicknesstwmmEditFieldLabel = uilabel(app.InputParametersPanel);
            app.WebThicknesstwmmEditFieldLabel.Text = 'Web Thickness, tw (mm)';
            app.WebThicknesstwmmEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.WebThicknesstwmmEditField.ValueChangedFcn = createCallbackFcn(app, @WebThicknesstwmmEditFieldValueChanged, true);
            app.OutputParameterPanel = uipanel(app.UIFigure);
            app.OutputParameterPanel.Title = 'Output Parameter';
            app.WebPostbucklingResistantVkNLabel = uilabel(app.OutputParameterPanel);
            app.WebPostbucklingResistantVkNLabel.Text = 'Web Post buckling Resistant ,V  (kN)';
            app.WebPostbucklingResistantVkNEditField = uieditfield(app.OutputParameterPanel, 'numeric');
            app.WebPostbucklingResistantVkNEditField.ValueChangedFcn = createCallbackFcn(app, @WebPostbucklingResistantVkNEditFieldValueChanged, true);
            app.SolveButton = uibutton(app.OutputParameterPanel, 'state');
            app.SolveButton.ValueChangedFcn = createCallbackFcn(app, @SolveButtonValueChanged, true);
            app.SolveButton.Text = 'Solve';
            app.ThisGUIisdevelopedbyDrRabeeShamassLabel = uilabel(app.OutputParameterPanel);
            app.ThisGUIisdevelopedbyDrRabeeShamassLabel.Text = 'This GUI is developed by Dr. Rabee Shamass';
            app.Label = uilabel(app.OutputParameterPanel);
            app.Label.Text = 'School of Built Environment and Architecture';
            app.Label_2 = uilabel(app.OutputParameterPanel);
            app.Label_2.Text = 'London South Bank University, London, UK';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Capture.PNG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WPB_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end